classdef PermutationTest_v1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        ui_b2i                    matlab.ui.container.Panel
        txt_if_save2              matlab.ui.control.Label
        b2i                       matlab.ui.control.Button
        if_savepath               matlab.ui.control.Label
        bf_added                  matlab.ui.control.TextArea
        ui_msg                    matlab.ui.container.Panel
        msg                       matlab.ui.control.ListBox
        PermutationTestButton     matlab.ui.control.Button
        plottog                   matlab.ui.control.CheckBox
        Mepstog                   matlab.ui.control.CheckBox
        CIen_stat                 matlab.ui.control.Label
        CIet_stat                 matlab.ui.control.Label
        CIcn_stat                 matlab.ui.control.Label
        CIct_stat                 matlab.ui.control.Label
        ENgn                      matlab.ui.control.EditField
        ETgn                      matlab.ui.control.EditField
        CNgn                      matlab.ui.control.EditField
        CTgn                      matlab.ui.control.EditField
        Mxlstog                   matlab.ui.control.CheckBox
        Msavedir                  matlab.ui.control.EditField
        Msave                     matlab.ui.control.Button
        ENaive                    matlab.ui.control.Label
        CIen                      matlab.ui.control.TextArea
        CIet                      matlab.ui.control.TextArea
        ETrained                  matlab.ui.control.Label
        CNaive                    matlab.ui.control.Label
        CIcn                      matlab.ui.control.TextArea
        CIct                      matlab.ui.control.TextArea
        CTraineed                 matlab.ui.control.Label
        ofrounds1100000Label      matlab.ui.control.Label
        PTrn                      matlab.ui.control.NumericEditField
        PTresultsL                matlab.ui.control.TextArea
        PTresults                 matlab.ui.control.TextArea
        DataSetEditFieldLabel     matlab.ui.control.Label
        DataSet                   matlab.ui.control.EditField
        ExperimentEditFieldLabel  matlab.ui.control.Label
        ExpGN                     matlab.ui.control.EditField
        ControlLabel              matlab.ui.control.Label
        CtrlGN                    matlab.ui.control.EditField
        title                     matlab.ui.control.Label
        txt_ver                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        data = []; % Description
    end
    
    methods (Access = private)
        %         function CI_list_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to CI_list (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        
        %         function listbox10_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to listbox10 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function listbox11_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to listbox11 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function listbox12_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to listbox12 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function listbox13_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to listbox13 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function listbox15_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to listbox15 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function listbox16_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to listbox16 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function listbox8_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to listbox8 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function listbox9_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to listbox9 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: listbox controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        
        %         function txt_aa1_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_aa1 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_aa2_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_aa2 (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_act_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_act (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_dpos_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_dpos (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_exp_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_exp (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_imp_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_imp (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_nai_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_nai (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_temhi_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_temhi (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_temlo_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_temlo (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end
        %
        %         function txt_tpos_CreateFcn(app, hObject, eventdata, handles)
        %             % hObject    handle to txt_tpos (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    empty - handles not created until after all CreateFcns called
        %
        %             % Hint: edit controls usually have a white background on Windows.
        %             %       See ISPC and COMPUTER.
        %             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
        %                 set(hObject,'BackgroundColor','white');
        %             end
        %         end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CIV_v1_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to CIV_v1 (see VARARGIN)
            %
            %             % Choose default command line output for CIV_v1
            %             app.output = hObject;
            %             app.FileName = [];
            %             app.FileName_full = [];
            %             app.FNmtx = [];
            %             app.ANmtx = [];
            %             app.raw = [];
            %             app.idxplot= 0;
            %             app.head = {'File','Arena','quality','copulation','courtship'};
            %
            %             % Update handles structure
            %             guidata(hObject, handles);
            app.data.EN.CI = [];
            app.data.EN.n = 0;
            app.data.EN.med = nan;
            app.data.EN.mea = nan;
            app.data.EN.std = nan;
            app.data.EN.sem = nan;
            app.data.ET.CI = [];
            app.data.ET.n = 0;
            app.data.ET.med = nan;
            app.data.ET.mea = nan;
            app.data.ET.std = nan;
            app.data.ET.sem = nan;
            app.data.CN.CI = [];
            app.data.CN.n = 0;
            app.data.CN.med = nan;
            app.data.CN.mea = nan;
            app.data.CN.std = nan;
            app.data.CN.sem = nan;
            app.data.CT.CI = [];
            app.data.CT.n = 0;
            app.data.CT.med = nan;
            app.data.CT.mea = nan;
            app.data.CT.std = nan;
            app.data.CT.sem = nan;
            
        end

        % Button pushed function: b2i
        function b2i_Callback(app, event)
            % hObject    handle to b2i (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [Fileb,SaveP] = CC_index_b2i;
            
            %             Fileb = cellfun(@(x)([Pathb x char(10)]),Fileb,'UniformOutput',false);
            
            set(app.bf_added,'value',Fileb);
            set(app.if_savepath,'text',SaveP);
            %             guidata(app, data);
        end

        % Value changed function: CIen
        function CIenValueChanged(app, event)
            value = app.CIen.Value;
            EN = [];
            EN.CI=cell2mat(cellfun(@(x)str2double(x), value,'uni',0));
            EN.CI(isnan(EN.CI))=[];
            
            EN.n = length(EN.CI);
            EN.med = nanmedian(EN.CI);
            EN.mea = nanmean(EN.CI);
            EN.std = nanstd(EN.CI);
            EN.sem = nansem(EN.CI);
            if EN.n>1
                set(app.CIen_stat,'Text',...
                    {['Count: ', num2str(EN.n)];...
                    ['Median: ', num2str(EN.med)];...
                    ['Mean: ', num2str(EN.mea)];...
                    ['StdDev: ', num2str(EN.std)];...
                    ['S.E.M.: ', num2str(EN.sem)]});
            elseif EN.n==1
                set(app.CIen_stat,'Text',...
                    {['Count: ', num2str(EN.n)];...
                    ['Median: ', num2str(EN.med)];...
                    ['Mean: ', num2str(EN.mea)];...
                    'StdDev: requires 2 samples';...
                    'S.E.M.: requires 2 samples'});
            elseif EN.n==0
                set(app.CIen_stat,'Text',...
                    {['Count: ', num2str(EN.n)];...
                    'Median:  requires 1 samples';...
                    'Mean: requires 1 samples';...
                    'StdDev: requires 2 samples';...
                    'S.E.M.: requires 2 samples'});
            end
            
            app.data.EN = EN;
            %             guidata(app,adata);
        end

        % Value changed function: CIet
        function CIetValueChanged(app, event)
            value = app.CIet.Value;
            ET = [];
            ET.CI=cell2mat(cellfun(@(x)str2double(x), value,'uni',0));
            ET.CI(isnan(ET.CI))=[];
            
            ET.n = length(ET.CI);
            ET.med = nanmedian(ET.CI);
            ET.mea = nanmean(ET.CI);
            ET.std = nanstd(ET.CI);
            ET.sem = nansem(ET.CI);
            if ET.n>1
                set(app.CIet_stat,'Text',...
                    {['Count: ', num2str(ET.n)];...
                    ['Median: ', num2str(ET.med)];...
                    ['Mean: ', num2str(ET.mea)];...
                    ['StdDev: ', num2str(ET.std)];...
                    ['S.E.M.: ', num2str(ET.sem)]});
            elseif ET.n==1
                set(app.CIet_stat,'Text',...
                    {['Count: ', num2str(ET.n)];...
                    ['Median: ', num2str(ET.med)];...
                    ['Mean: ', num2str(ET.mea)];...
                    'StdDev: requires 2 samples';...
                    'S.E.M.: requires 2 samples'});
            elseif ET.n==0
                set(app.CIet_stat,'Text',...
                    {['Count: ', num2str(ET.n)];...
                    'Median:  requires 1 samples';...
                    'Mean: requires 1 samples';...
                    'StdDev: requires 2 samples';...
                    'S.E.M.: requires 2 samples'});
            end
            
            app.data.ET = ET;
            %             guidata(app,data);
        end

        % Value changed function: CIcn
        function CIcnValueChanged(app, event)
            value = app.CIcn.Value;
            CN = [];
            CN.CI=cell2mat(cellfun(@(x)str2double(x), value,'uni',0));
            CN.CI(isnan(CN.CI))=[];
            
            CN.n = length(CN.CI);
            CN.med = nanmedian(CN.CI);
            CN.mea = nanmean(CN.CI);
            CN.std = nanstd(CN.CI);
            CN.sem = nansem(CN.CI);
            if CN.n>1
                set(app.CIcn_stat,'Text',...
                    {['Count: ', num2str(CN.n)];...
                    ['Median: ', num2str(CN.med)];...
                    ['Mean: ', num2str(CN.mea)];...
                    ['StdDev: ', num2str(CN.std)];...
                    ['S.E.M.: ', num2str(CN.sem)]});
            elseif CN.n==1
                set(app.CIcn_stat,'Text',...
                    {['Count: ', num2str(CN.n)];...
                    ['Median: ', num2str(CN.med)];...
                    ['Mean: ', num2str(CN.mea)];...
                    'StdDev: requires 2 samples';...
                    'S.E.M.: requires 2 samples'});
            elseif CN.n==0
                set(app.CIcn_stat,'Text',...
                    {['Count: ', num2str(CN.n)];...
                    'Median:  requires 1 samples';...
                    'Mean: requires 1 samples';...
                    'StdDev: requires 2 samples';...
                    'S.E.M.: requires 2 samples'});
            end
            
            app.data.CN = CN;
            %             guidata(hObject,data);
            
        end

        % Value changed function: CIct
        function CIctValueChanged(app, event)
            value = app.CIct.Value;
            CT = [];
            CT.CI=cell2mat(cellfun(@(x)str2double(x), value,'uni',0));
            CT.CI(isnan(CT.CI))=[];
            
            CT.n = length(CT.CI);
            CT.med = nanmedian(CT.CI);
            CT.mea = nanmean(CT.CI);
            CT.std = nanstd(CT.CI);
            CT.sem = nansem(CT.CI);
            if CT.n>1
                set(app.CIct_stat,'Text',...
                    {['Count: ', num2str(CT.n)];...
                    ['Median: ', num2str(CT.med)];...
                    ['Mean: ', num2str(CT.mea)];...
                    ['StdDev: ', num2str(CT.std)];...
                    ['S.E.M.: ', num2str(CT.sem)]});
            elseif CT.n==1
                set(app.CIct_stat,'Text',...
                    {['Count: ', num2str(CT.n)];...
                    ['Median: ', num2str(CT.med)];...
                    ['Mean: ', num2str(CT.mea)];...
                    'StdDev: requires 2 samples';...
                    'S.E.M.: requires 2 samples'});
            elseif CT.n==0
                set(app.CIct_stat,'Text',...
                    {['Count: ', num2str(CT.n)];...
                    'Median:  requires 1 samples';...
                    'Mean: requires 1 samples';...
                    'StdDev: requires 2 samples';...
                    'S.E.M.: requires 2 samples'});
            end
            
            app.data.CT = CT;
            %             guidata(obj,app.data);
        end

        % Button pushed function: PermutationTestButton
        function PermutationTestButtonPushed(app, event)
            gd= app.data;
            %check experimental data
            if isempty(gd.EN.CI)||isempty(gd.ET.CI)
                msgBox = app.msg.Items;
                msgBox = [{['Error:(',datestr(now,31),')']},{'Insufficient data.'},{'------'},msgBox];
                set(app.msg,'Items',msgBox);
                set(app.msg,'Value',{});
                return;
            end
            %get permutation rounds
            rn = floor(app.PTrn.Value);
            if rn<1 || rn>100000
                rn = 10000;
                set(app.msg,'value','Number of permuattion rounds should be between 1-100000');
            end
            
            %permutation test
            [ptExp,ptHd] = permutationtest(rn,gd.EN.CI,gd.ET.CI,gd.CN.CI,gd.CT.CI);
            ptCtrl = permutationtest(rn,gd.CN.CI,gd.CT.CI);
            ptResult = nan(16,1);
            ptResult(1:5) = ptExp(1:5);
            ptResult(6:10) = ptCtrl(1:5);
            ptResult(11:16) = ptExp(9:14);
            ptHeader = cell(16,1);
            ptHeader(1:5) = ptHd(1:5);
            ptHeader(6:10)= {'LI ctrlByMedian(%)','p ctrlByMedian','LI ctrlByMean(%)','p ctrlByMean','p ctrlByRanksum'};
            ptHeader(11:16) = ptHd(9:14);
            
            
            myResult = { num2str(ptExp(1));...
                num2str(ptExp(2));...
                num2str(ptExp(3));...
                num2str(ptExp(4));...
                num2str(ptExp(5));...
                ' ';...
                num2str(ptCtrl(1));...
                num2str(ptCtrl(2));...
                num2str(ptCtrl(3));...
                num2str(ptCtrl(4));...
                num2str(ptCtrl(5));...
                ' ';...
                num2str(ptExp(9));...
                num2str(ptExp(10));...
                num2str(ptExp(11));...
                ' ';...
                num2str(ptExp(12));...
                num2str(ptExp(13));...
                num2str(ptExp(14))};
            set(app.PTresults,'Value',myResult);
            
            %             togctrl = isempty(gd.CN.CI) & isempty(gd.CT.CI);
            togplt = app.plottog.Value;
            if togplt
                GN{1} = app.ENgn.Value;
                GN{2} = app.ETgn.Value;
                GN{3} = app.CNgn.Value;
                GN{4} = app.CTgn.Value;
                
                GNLI{1} = app.ExpGN.Value;
                GNLI{2} = app.CtrlGN.Value;
                
                dataset = app.DataSet.Value;
                togeps = app.Mepstog.Value;
                togxls = app.Mxlstog.Value;
                savePath = fileparts(app.Msavedir.Value);
                if togeps || togxls
                    if isempty(savePath)
                        savePath = uigetdir('D:\','Save data to:');
                        if ~ischar(savePath)
                            msgBox = app.msg.Items;
                            msgBox = [{['Warning:(',datestr(now,31),')']},{'Saving folder not selected.'},{'------'},msgBox];
                            set(app.msg,'Items',msgBox);
                            set(app.msg,'Value',{});
                            return;
                        end
                        set(app.Msavedir, 'Value',[savePath,'\']);
                    end
                    if ~exist(savePath,'dir')
                        mkdir(savePath);
                    end
                end
                [~,sig]=plotCIV(gd,ptResult,ptHeader,GN,GNLI,savePath,dataset,togeps,togxls);
                app.data.Sig = sig;
            end
        end

        % Button pushed function: Msave
        function MsaveButtonPushed(app, event)
            savePath = uigetdir('D:\','Save data to:');
            if ~ischar(savePath)
                msgBox = app.msg.Items;
                msgBox = [{['Warning:(',datestr(now,31),')']},{'Saving folder not selected.'},{'------'},msgBox];
                set(app.msg,'Items',msgBox);
                set(app.msg,'Value',{});
                return;
            end
            set(app.Msavedir, 'Value',[savePath,'\']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CIV_v1';
            app.ui_b2i = uipanel(app.figure1);
            app.ui_b2i.Title = 'Batch indexes to individual files';
            app.txt_if_save2 = uilabel(app.ui_b2i);
            app.txt_if_save2.Text = 'Saved to:';
            app.b2i = uibutton(app.ui_b2i, 'push');
            app.b2i.ButtonPushedFcn = createCallbackFcn(app, @b2i_Callback, true);
            app.b2i.Text = 'B 2 I';
            app.if_savepath = uilabel(app.ui_b2i);
            app.if_savepath.Text = '';
            app.bf_added = uitextarea(app.ui_b2i);
            app.bf_added.Editable = 'off';
            app.bf_added.Value = {'batch CI files processed'; ''};
            app.ui_msg = uipanel(app.figure1);
            app.ui_msg.Title = 'Messages';
            app.msg = uilistbox(app.ui_msg);
            app.msg.Items = {'Hello there'};
            app.msg.Multiselect = 'on';
            app.msg.Value = {'Hello there'};
            app.PermutationTestButton = uibutton(app.figure1, 'push');
            app.PermutationTestButton.ButtonPushedFcn = createCallbackFcn(app, @PermutationTestButtonPushed, true);
            app.PermutationTestButton.Text = 'Permutation Test';
            app.plottog = uicheckbox(app.figure1);
            app.plottog.Text = 'w. index plot';
            app.plottog.Value = true;
            app.Mepstog = uicheckbox(app.figure1);
            app.Mepstog.Text = 'save png files';
            app.CIen_stat = uilabel(app.figure1);
            app.CIen_stat.Text = {'Count: 0'; 'Median: requires 1 sample'; 'Mean: requires 1 sample'; 'StdDev: requires 2 samples'; 'S.E.M.: requires 2 samples'};
            app.CIet_stat = uilabel(app.figure1);
            app.CIet_stat.Text = {'Count: 0'; 'Median: requires 1 sample'; 'Mean: requires 1 sample'; 'StdDev: requires 2 samples'; 'S.E.M.: requires 2 samples'};
            app.CIcn_stat = uilabel(app.figure1);
            app.CIcn_stat.Text = {'Count: 0'; 'Median: requires 1 sample'; 'Mean: requires 1 sample'; 'StdDev: requires 2 samples'; 'S.E.M.: requires 2 samples'};
            app.CIct_stat = uilabel(app.figure1);
            app.CIct_stat.Text = {'Count: 0'; 'Median: requires 1 sample'; 'Mean: requires 1 sample'; 'StdDev: requires 2 samples'; 'S.E.M.: requires 2 samples'};
            app.ENgn = uieditfield(app.figure1, 'text');
            app.ENgn.Value = 'group name 1';
            app.ETgn = uieditfield(app.figure1, 'text');
            app.ETgn.Value = 'group name 2';
            app.CNgn = uieditfield(app.figure1, 'text');
            app.CNgn.Value = 'group name 3';
            app.CTgn = uieditfield(app.figure1, 'text');
            app.CTgn.Value = 'group name 4';
            app.Mxlstog = uicheckbox(app.figure1);
            app.Mxlstog.Text = 'save xlsx files';
            app.Mxlstog.Value = true;
            app.Msavedir = uieditfield(app.figure1, 'text');
            app.Msave = uibutton(app.figure1, 'push');
            app.Msave.ButtonPushedFcn = createCallbackFcn(app, @MsaveButtonPushed, true);
            app.Msave.Text = 'save to:';
            app.ENaive = uilabel(app.figure1);
            app.ENaive.Text = 'CI Naive';
            app.CIen = uitextarea(app.figure1);
            app.CIen.ValueChangedFcn = createCallbackFcn(app, @CIenValueChanged, true);
            app.CIet = uitextarea(app.figure1);
            app.CIet.ValueChangedFcn = createCallbackFcn(app, @CIetValueChanged, true);
            app.ETrained = uilabel(app.figure1);
            app.ETrained.Text = 'CI Trained';
            app.CNaive = uilabel(app.figure1);
            app.CNaive.Text = 'CI Naive';
            app.CIcn = uitextarea(app.figure1);
            app.CIcn.ValueChangedFcn = createCallbackFcn(app, @CIcnValueChanged, true);
            app.CIct = uitextarea(app.figure1);
            app.CIct.ValueChangedFcn = createCallbackFcn(app, @CIctValueChanged, true);
            app.CTraineed = uilabel(app.figure1);
            app.CTraineed.Text = 'CI Trained';
            app.ofrounds1100000Label = uilabel(app.figure1);
            app.ofrounds1100000Label.Text = '# of rounds (1-100000)';
            app.PTrn = uieditfield(app.figure1, 'numeric');
            app.PTrn.ValueDisplayFormat = '%.0f';
            app.PTrn.Value = 100000;
            app.PTresultsL = uitextarea(app.figure1);
            app.PTresultsL.Editable = 'off';
            app.PTresultsL.Value = {'LI exp_byMedian(%):'; 'p exp_byMedian:'; 'LI exp_byMean(%):'; 'p exp_byMean:'; 'p exp_byRanksum:'; ''; 'LI ctrl_byMedian(%):'; 'p ctrl_byMedian:'; 'LI Ctrl_byMean(%):'; 'p ctrl_byMean:'; 'p ctrl_byRanksum:'; ''; 'p E==C byMedian:'; 'p E=<C byMedian:'; 'p E>=C byMedian:'; ''; 'p E==C byMean:'; 'p E=<C byMean:'; 'p E>=C byMean:'};
            app.PTresults = uitextarea(app.figure1);
            app.DataSetEditFieldLabel = uilabel(app.figure1);
            app.DataSetEditFieldLabel.Text = 'Data Set';
            app.DataSet = uieditfield(app.figure1, 'text');
            app.DataSet.Value = 'Dataset_protocol_geno';
            app.ExperimentEditFieldLabel = uilabel(app.figure1);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExpGN = uieditfield(app.figure1, 'text');
            app.ExpGN.Value = 'Exp';
            app.ControlLabel = uilabel(app.figure1);
            app.ControlLabel.Text = 'Control';
            app.CtrlGN = uieditfield(app.figure1, 'text');
            app.CtrlGN.Value = 'Ctrl';
            app.title = uilabel(app.figure1);
            app.title.Text = 'Permutation Test';
            app.txt_ver = uilabel(app.figure1);
            app.txt_ver.Text = 'Ver 1.1 leiz 20211128';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PermutationTest_v1_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @CIV_v1_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end